classdef QuadSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ControlPanelUIFigure  matlab.ui.Figure
        ResetButton           matlab.ui.control.Button
        ThrottleLabel         matlab.ui.control.Label
        bar_throttle          matlab.ui.control.Slider
        TargetangleLabel      matlab.ui.control.Label
        txt_angle             matlab.ui.control.NumericEditField
        lb_status             matlab.ui.control.Label
        btn_pause             matlab.ui.control.Button
        btn_start             matlab.ui.control.Button
        Label_2               matlab.ui.control.Label
        bar_setAngle          matlab.ui.control.Slider
        Label_3               matlab.ui.control.Label
        disp_angle            matlab.ui.control.SemicircularGauge
        txt_throttle          matlab.ui.control.NumericEditField
        lamp                  matlab.ui.control.Lamp
        bar_az                matlab.ui.control.Slider
        bar_el                matlab.ui.control.Slider
    end

% varibales ======================================================================================================================================
    properties (Access = private)
        fig_quad = figure
        timer_plot
        
        % view angle for plot
        az = 90;
        el = 0;
        
        % variables for physics
        pwm
        angles = [0 0 0]';
        angles_des = [0 0 0];
        omega = [0 0 0]';
        v = [0 0 0]';
        pos = [0 0 0]';
        angle_dot = [0 0 0]';
        
    end

% functions ======================================================================================================================================
    methods (Access = private)
    
        function isr_plot(app, ~, ~)
            
            % update controller
            app.pwm = attitude_controller(app.angles_des, app.angles, app.omega, 20);
            
            [R, app.pos, app.angles, app.omega] = quad_dynamics(app.pwm, [], []);
            app.pos = [0 0 0]';
            
            
            % plotting 
            figure(app.fig_quad)
            clf
            hold on
            plot_quad(R, app.pos);
            hold off
            ylim([-0.6 0.6])
            grid on;
            axis equal
            view(app.az, app.el);
            
            % update print out
            app.lb_status.Text = 'Can print out other info';
                
        end


    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            init % initialisation for maths
            
            % init for app
            app.lamp.Color = 'k';


            app.timer_plot = timer;
            app.timer_plot.Period = 0.01;
            app.timer_plot.ExecutionMode = 'fixedSpacing';
            app.timer_plot.TimerFcn = @app.isr_plot;
            
            set(app.fig_quad, 'defaultfigureposition', [500 200 600 600])
            
        end

        % Close request function: ControlPanelUIFigure
        function ControlPanelUIFigureCloseRequest(app, event)
            close all;
            delete(app);
            delete(app.timer_plot);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
                    % view angle for plot
            app.az = 90;
            app.el = 0;
            
            % variables for physics
            app.pwm = [0 0 0 0];
            app.angles = [0 0 0]';
            app.angles_des = [0 0 0];
            app.omega = [0 0 0]';
            app.v = [0 0 0]';
            app.pos = [0 0 0]';
            app.angle_dot = [0 0 0]';
            
            app.disp_angle.Value = 0;
            app.txt_angle.Value = 0;
            app.bar_setAngle.Value = 0;
        end

        % Value changed function: bar_az
        function bar_azValueChanged(app, event)
            value = app.bar_az.Value;
            app.az = round(value/5)*5;
            app.bar_az.Value = app.az;
        end

        % Value changed function: bar_el
        function bar_elValueChanged(app, event)
            value = app.bar_el.Value;
            app.el = round(value/5)*5;
            app.bar_el.Value = app.el;
        end

        % Value changed function: bar_setAngle
        function bar_setAngleValueChanged(app, event)
            value = app.bar_setAngle.Value;
            app.txt_angle.Value = value;
            app.disp_angle.Value = value;
            app.angles_des(1) = value;
        end

        % Value changed function: bar_throttle
        function bar_throttleValueChanged(app, event)
            value = app.bar_throttle.Value;
            app.txt_throttle.Value = value;
        end

        % Button pushed function: btn_pause
        function btn_pausePushed(app, event)
            stop(app.timer_plot);
            app.lamp.Color = 'k';
        end

        % Button pushed function: btn_start
        function btn_startButtonPushed(app, event)
            start(app.timer_plot);
            app.lamp.Color = 'g';
        end

        % Value changed function: txt_angle
        function txt_angleValueChanged(app, event)
            value = app.txt_angle.Value;
            app.bar_setAngle.Value = value;
            app.disp_angle.Value = value;
            app.angles_des(1) = value;
        end

        % Value changed function: txt_throttle
        function txt_throttleValueChanged(app, event)
            value = app.txt_throttle.Value;
            app.bar_throttle.Value = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ControlPanelUIFigure = uifigure;
            app.ControlPanelUIFigure.Name = 'Control Panel';
            app.ControlPanelUIFigure.CloseRequestFcn = createCallbackFcn(app, @ControlPanelUIFigureCloseRequest, true);
            app.ResetButton = uibutton(app.ControlPanelUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ThrottleLabel = uilabel(app.ControlPanelUIFigure);
            app.ThrottleLabel.Text = 'Throttle';
            app.bar_throttle = uislider(app.ControlPanelUIFigure);
            app.bar_throttle.ValueChangedFcn = createCallbackFcn(app, @bar_throttleValueChanged, true);
            app.TargetangleLabel = uilabel(app.ControlPanelUIFigure);
            app.TargetangleLabel.Text = 'Target angle';
            app.txt_angle = uieditfield(app.ControlPanelUIFigure, 'numeric');
            app.txt_angle.ValueChangedFcn = createCallbackFcn(app, @txt_angleValueChanged, true);
            app.txt_angle.Limits = [-90 90];
            app.txt_angle.ValueDisplayFormat = '%.0f';
            app.lb_status = uilabel(app.ControlPanelUIFigure);
            app.lb_status.Text = '';
            app.btn_pause = uibutton(app.ControlPanelUIFigure, 'push');
            app.btn_pause.ButtonPushedFcn = createCallbackFcn(app, @btn_pausePushed, true);
            app.btn_pause.Text = 'Pause';
            app.btn_start = uibutton(app.ControlPanelUIFigure, 'push');
            app.btn_start.ButtonPushedFcn = createCallbackFcn(app, @btn_startButtonPushed, true);
            app.btn_start.Text = 'Start';
            app.Label_2 = uilabel(app.ControlPanelUIFigure);
            app.Label_2.Text = '';
            app.bar_setAngle = uislider(app.ControlPanelUIFigure);
            app.bar_setAngle.Limits = [-90 90];
            app.bar_setAngle.ValueChangedFcn = createCallbackFcn(app, @bar_setAngleValueChanged, true);
            app.Label_3 = uilabel(app.ControlPanelUIFigure);
            app.Label_3.Text = '';
            app.disp_angle = uigauge(app.ControlPanelUIFigure, 'semicircular');
            app.disp_angle.Limits = [-90 90];
            app.disp_angle.ScaleDirection = 'counterclockwise';
            app.txt_throttle = uieditfield(app.ControlPanelUIFigure, 'numeric');
            app.txt_throttle.ValueChangedFcn = createCallbackFcn(app, @txt_throttleValueChanged, true);
            app.lamp = uilamp(app.ControlPanelUIFigure);
            app.bar_az = uislider(app.ControlPanelUIFigure);
            app.bar_az.Limits = [-90 90];
            app.bar_az.MajorTicks = [-90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90];
            app.bar_az.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', ''};
            app.bar_az.ValueChangedFcn = createCallbackFcn(app, @bar_azValueChanged, true);
            app.bar_az.MinorTicks = [];
            app.bar_el = uislider(app.ControlPanelUIFigure);
            app.bar_el.Limits = [-90 90];
            app.bar_el.MajorTicks = [-90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90];
            app.bar_el.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', ''};
            app.bar_el.ValueChangedFcn = createCallbackFcn(app, @bar_elValueChanged, true);
            app.bar_el.MinorTicks = [];
        end
    end

    methods (Access = public)

        % Construct app
        function app = QuadSim

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ControlPanelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ControlPanelUIFigure)
        end
    end
end